classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        AjoutdusignalButton  matlab.ui.control.Button
        AjoutdubruitButton   matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function buttonPlot
    % Create a figure window
    fig = uifigure;
    
    % Create a UI axes
    ax = uiaxes('Parent',fig,...
                'Units','pixels',...
                'Position', [104, 123, 300, 201]);   
    
    % Create a push button
    btn = uibutton(fig,'push',...
                   'Position',[420, 218, 100, 22],...
                   'ButtonPushedFcn', @(btn,event) plotButtonPushed(btn,ax));
    end
    
    % Create the function for the ButtonPushedFcn callback
    function plotButtonPushed(btn,ax)
            x = linspace(0,2*pi,100);
            y = sin(x);
            plot(ax,x,y)
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AjoutdusignalButton
        function AjoutdusignalButtonPushed(app, event)
         
            plot(ones(1,20));


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {584, 584};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AjoutdubruitButton = uibutton(app.LeftPanel, 'push');
            app.AjoutdubruitButton.Text = 'Ajout du bruit';
            app.AjoutdusignalButton = uibutton(app.LeftPanel, 'push');
            app.AjoutdusignalButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutdusignalButtonPushed, true);
            app.AjoutdusignalButton.Text = 'Ajout du signal';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Axe temporel')
            ylabel(app.UIAxes, 'Axe de l''amplitude')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.AjoutdubruitButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end